classdef sim_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu_2                         matlab.ui.container.Menu
        Menu_3                         matlab.ui.container.Menu
        About                          matlab.ui.container.Menu
        InfoMenu                       matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TipodeEventoButtonGroup        matlab.ui.container.ButtonGroup
        EstratiformeButton             matlab.ui.control.RadioButton
        ConvectivoButton               matlab.ui.control.RadioButton
        MximoZSpinnerLabel             matlab.ui.control.Label
        MximoZSpinner                  matlab.ui.control.Spinner
        deceldasconlluviaSpinnerLabel  matlab.ui.control.Label
        deceldasconlluviaSpinner       matlab.ui.control.Spinner
        SimularButton                  matlab.ui.control.Button
        ReiniciarButton                matlab.ui.control.Button
        DescargarButton                matlab.ui.control.Button
        NumHazSpinnerLabel             matlab.ui.control.Label
        NumHazSpinner                  matlab.ui.control.Spinner
        NewButton                      matlab.ui.control.Button
        TamcellSpinner_label           matlab.ui.control.Label
        TamcellSpinner                 matlab.ui.control.Spinner
        NumEventos                     matlab.ui.control.Label
        NumEventosSpinner              matlab.ui.control.Spinner
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        ZmaxLabel                      matlab.ui.control.Label
        QtyDatos                       matlab.ui.control.Label
        ZmaxVal                        matlab.ui.control.Label
        QtyDatos_ang                   matlab.ui.control.Label
        TipoLabel                      matlab.ui.control.Label
        EvVal                          matlab.ui.control.Label
        QtyDatos_2                     matlab.ui.control.Label
        QtyDatos_cell                  matlab.ui.control.Label
        SIM                            matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        percent                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        idLabel                        matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        status                         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            
            app.SimularButton.Enable=false;
            progress="Parámetros De Simulación Recibidos";
            app.status.Text=sprintf('%s',progress);
            rows = 2000;
            columns = 2000;
            grayImage=noiseonf(rows,columns,1.55);
            imageSize = [rows, columns]; % The created image size will be 90% of the screen.
            a=size(imageSize);
            eventtype=app.EvVal.Text;
            
            if eventtype=="Estratiforme"
                grayImage = noiseonf(rows,columns,1.45);
                app.QtyDatos_ang.Text=sprintf('%d',1.45)
            end
            if eventtype=="Convectivo"
                grayImage = noiseonf(rows,columns,1.75);
                app.QtyDatos_ang.Text=sprintf('%d',1.75)

            end
            
            num_haces=app.NumHazSpinner.Value;
            ang_resolution=round(360/num_haces);
            num_celdas=app.TamcellSpinner.Value;
            num_celdas=round(664*450/num_celdas);
            Parea=app.deceldasconlluviaSpinner.Value;
            
            num_eventos=app.NumEventosSpinner.Value;
                        
            % Se visualizan los datos con los que se generó la simulación
            Zmax= app.MximoZSpinner.Value;        % Se debe definir un valor máximo de Z para determinar la "severidad" del evento meteorológico.
            app.ZmaxVal.Text=sprintf('%d',Zmax);
            app.QtyDatos_cell.Text=sprintf('%d',num_celdas);
            app.QtyDatos_ang.Text=sprintf('%d',num_haces);
            progress="Generando datos simulados";
            app.status.Text=sprintf('%s',progress);
            
            imcenter=find_bestcenter(grayImage,Parea,Zmax)
            
            im_centerx=imcenter(1,1)
            im_centery=imcenter(1,2)
            
            despl_calcx=round(20-40*rand(1,1))
            despl_calcy=round(20-40*rand(1,1))
            
            max_desplx=12*despl_calcx+im_centerx
            max_desply=12*despl_calcy+im_centery
            
            if(max_desplx>1500)
               im_centerx=1499-12*despl_calcx;
            end
           
             if(max_desply>1500)
               im_centery=1499-12*despl_calcy;
            end
            
            if(max_desplx<500)
               im_centerx=500-12*despl_calcx;
            end
           
            if(max_desply<500)
               im_centery=500-12*despl_calcy;
            end
            
            h=waitbar(0,'Generando datos...');
            
            
            simid=get_idsim();
            app.idLabel.Text=sprintf('%s',int2str(simid));
            foldernm=strcat("simulacion_",int2str(simid));
            mkdir(foldernm);
            
            
            path=pwd;
            filenmtxt=strcat(path,"/",foldernm,"/parameters_",int2str(simid),".txt");
            fileID = fopen(filenmtxt,'w');
            fprintf(fileID,'%6s %s\n','Parametros Simulacion',int2str(simid));
            fprintf(fileID,'%6s %d\n', 'Zmax',Zmax);
            fprintf(fileID,'%6s %d\n', '% area',Parea);
            fprintf(fileID,'%6s %d\n', 'Num eventos',num_eventos);
            fprintf(fileID,'%6s %d\n', 'Num haz',num_haces);
            fprintf(fileID,'%6s %d\n', 'Num celdas',num_celdas);
            
            
            fclose(fileID);


            for ig=1:1:num_eventos
                ia=int2str(ig);
                     
                im_centerx=despl_calcx+im_centerx
                im_centery=im_centery+despl_calcy
            
                radar_data_Z=gen_Zdr(grayImage,Zmax,Parea,num_celdas,ang_resolution,im_centerx,im_centery);
            
                progress="Datos Generados, obteniendo gráfica";
                app.status.Text=sprintf('%s',progress);
                
                percent=get_percZ(radar_data_Z);
                app.percent.Text=sprintf('%d',percent);

                
                imsim=graph_Z(radar_data_Z); 
                d=date;
                s=int2str(second(datetime));
                path=pwd;
                
                gif_filenm=strcat(path,"/",foldernm,"/imgsim",int2str(simid),".gif");

                [Bgif,map]=rgb2ind(imsim,256);
                
                if(ig>1)
                    imwrite(Bgif,map,gif_filenm,'gif','WriteMode','append','DelayTime',1);
                else
                    imwrite(Bgif,map,gif_filenm,'gif','LoopCount',Inf,'DelayTime',1);
                end
            
                % If it's MAC OS use line below
                filenm=strcat(path,"/",foldernm,"/imgsim",int2str(simid),"_",int2str(ig),".png");
                
                %If it's windows OS use line below
                %filenm=strcat(path,"\",foldernm,"\imgsim",int2str(simid),"_",int2str(ig),".png");
                
                gen_data_file(radar_data_Z,path,simid,foldernm,ig);
                %% For generation of data file in excel file
                %datafilenm=strcat(path,"/",foldernm,"/Zh_",int2str(simid),"_",int2str(ig),".xlsx");
           
                %datafilenm=("Zh_sim.xlsx");
                %xlswrite(datafilenm,radar_data_Z);
                imwrite(imsim,filenm);
                %app.Image.ImageSource=filenm;
                app.Image.ImageSource=gif_filenm;
                
                waitbar(ig/num_eventos);   
                
                
            end
            
            close(h);
           
            app.DescargarButton.Enable=true;
            app.ReiniciarButton.Visible=true;
                        zipfilenm=strcat(path,"/",foldernm,"/sim",int2str(simid),".zip");

            zip(zipfilenm,strcat(path,"/",foldernm));
            progress="Simulación Terminada";
            app.status.Text=sprintf('%s',progress);
            
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            app.Image.ImageSource='blank.png';
            app.ReiniciarButton.Visible=false;
            app.DescargarButton.Enable=false;
            app.SimularButton.Enable=true;
            grayImage=zeros(2000,2000); %#ok<NASGU> 
        end

        % Selection changed function: TipodeEventoButtonGroup
        function TipodeEventoButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodeEventoButtonGroup.SelectedObject;
            V1=app.EstratiformeButton.Value;
            V2=app.ConvectivoButton.Value;
            
            if V1 == 1
                app.QtyDatos_ang.Text=sprintf('%d',1);
                app.MximoZSpinner.Limits=[0 40];
                app.MximoZSpinner.Value=20;
                app.EvVal.Text=sprintf('%s',"Estratiforme");
            end
           
            if V2 == 1
                app.QtyDatos_ang.Text=sprintf('%d',2);
                app.MximoZSpinner.Limits=[30 70];
                app.MximoZSpinner.Value=50;
                eventtype=2;
                app.EvVal.Text=sprintf('%s',"Convectivo");
            end
        end

        % Button pushed function: DescargarButton
        function DescargarButtonPushed(app, event)
            path=pwd;
            simid= app.idLabel.Text;
            macopen('Applications');
            %macopen(strcat(path,"/","simulacion_",simid,"/sim",simid));
                 
           
              %app.status.Text=sprintf('%s',simid);
            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            sim_1;
        end

        % Value changed function: MximoZSpinner
        function MximoZSpinnerValueChanged(app, event)
            value = app.MximoZSpinner.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {597, 597};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = 'Menu';
            app.Menu_3 = uimenu(app.Menu_2);
            app.Menu_3.Text = 'Menu';
            app.About = uimenu(app.UIFigure);
            app.About.Text = 'Acerca de';
            app.InfoMenu = uimenu(app.About);
            app.InfoMenu.Text = 'Info';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TipodeEventoButtonGroup = uibuttongroup(app.LeftPanel);
            app.TipodeEventoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodeEventoButtonGroupSelectionChanged, true);
            app.TipodeEventoButtonGroup.Title = 'Tipo de Evento';
            app.EstratiformeButton = uiradiobutton(app.TipodeEventoButtonGroup);
            app.EstratiformeButton.Text = 'Estratiforme';
            app.EstratiformeButton.Value = true;
            app.ConvectivoButton = uiradiobutton(app.TipodeEventoButtonGroup);
            app.ConvectivoButton.Text = 'Convectivo';
            app.MximoZSpinnerLabel = uilabel(app.LeftPanel);
            app.MximoZSpinnerLabel.Text = 'Máximo Z';
            app.MximoZSpinner = uispinner(app.LeftPanel);
            app.MximoZSpinner.Limits = [0 70];
            app.MximoZSpinner.ValueChangedFcn = createCallbackFcn(app, @MximoZSpinnerValueChanged, true);
            app.MximoZSpinner.Value = 20;
            app.deceldasconlluviaSpinnerLabel = uilabel(app.LeftPanel);
            app.deceldasconlluviaSpinnerLabel.Text = '% de celdas con lluvia';
            app.deceldasconlluviaSpinner = uispinner(app.LeftPanel);
            app.deceldasconlluviaSpinner.Limits = [0 100];
            app.deceldasconlluviaSpinner.Value = 20;
            app.SimularButton = uibutton(app.LeftPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.ReiniciarButton = uibutton(app.LeftPanel, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Visible = 'off';
            app.ReiniciarButton.Text = 'Reiniciar';
            app.DescargarButton = uibutton(app.LeftPanel, 'push');
            app.DescargarButton.ButtonPushedFcn = createCallbackFcn(app, @DescargarButtonPushed, true);
            app.DescargarButton.Enable = 'off';
            app.DescargarButton.Text = 'Descargar';
            app.NumHazSpinnerLabel = uilabel(app.LeftPanel);
            app.NumHazSpinnerLabel.Text = 'Num Haz';
            app.NumHazSpinner = uispinner(app.LeftPanel);
            app.NumHazSpinner.Step = 90;
            app.NumHazSpinner.Limits = [90 720];
            app.NumHazSpinner.Value = 360;
            app.NewButton = uibutton(app.LeftPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.TamcellSpinner_label = uilabel(app.LeftPanel);
            app.TamcellSpinner_label.Text = 'Tamaño Celda (m)';
            app.TamcellSpinner = uispinner(app.LeftPanel);
            app.TamcellSpinner.Step = 50;
            app.TamcellSpinner.Limits = [45 900];
            app.TamcellSpinner.Value = 450;
            app.NumEventos = uilabel(app.LeftPanel);
            app.NumEventos.Text = 'Eventos Consecutivos';
            app.NumEventosSpinner = uispinner(app.LeftPanel);
            app.NumEventosSpinner.Limits = [1 12];
            app.NumEventosSpinner.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout2);
            app.ZmaxLabel = uilabel(app.GridLayout2);
            app.ZmaxLabel.Text = 'Zmax:';
            app.QtyDatos = uilabel(app.GridLayout2);
            app.QtyDatos.Text = 'Datos:';
            app.ZmaxVal = uilabel(app.GridLayout2);
            app.QtyDatos_ang = uilabel(app.GridLayout2);
            app.TipoLabel = uilabel(app.GridLayout2);
            app.TipoLabel.Text = 'Tipo:';
            app.EvVal = uilabel(app.GridLayout2);
            app.EvVal.Text = 'Estratiforme';
            app.QtyDatos_2 = uilabel(app.GridLayout2);
            app.QtyDatos_2.Text = 'x';
            app.QtyDatos_cell = uilabel(app.GridLayout2);
            app.SIM = uilabel(app.GridLayout2);
            app.SIM.Text = 'SIMULADOR DE DATOS DE RADAR METEOROLÓGICO';
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageSource = 'logo_ud.png';
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.ImageSource = 'logo_MCIC.png';
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageSource = 'escala.png';
            app.percent = uilabel(app.GridLayout2);
            app.percent.Text = ' ';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = ' ';
            app.idLabel = uilabel(app.GridLayout2);
            app.idLabel.Text = 'id';
            app.XLabel = uilabel(app.GridLayout2);
            app.XLabel.Text = 'X';
            app.status = uilabel(app.GridLayout2);
            app.status.Text = 'Ingrese datos de Simulación';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sim_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end